classdef LUNGENTRAINER_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        EditField           matlab.ui.control.EditField
        Label               matlab.ui.control.Label
        UsersDropDown       matlab.ui.control.DropDown
        UsersDropDownLabel  matlab.ui.control.Label
        ShowDropDown        matlab.ui.control.DropDown
        ShowDropDownLabel   matlab.ui.control.Label
        StartButton         matlab.ui.control.Button
        RoundsSpinner       matlab.ui.control.Spinner
        RoundsSpinnerLabel  matlab.ui.control.Label
        TimesSpinner        matlab.ui.control.Spinner
        TimesSpinnerLabel   matlab.ui.control.Label
        UIAxes4             matlab.ui.control.UIAxes
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            I = imread("Title_image_EN.jpg");
            imagesc(app.UIAxes,I);axis(app.UIAxes,'tight');
            axis(app.UIAxes2,'off');
            axis(app.UIAxes4,'off');
            app.EditField.Visible = 'off';
            MyFolderInfo = dir('*.xlsx');
            [k1,k2]=size(MyFolderInfo);
            if k1>0
                for ik=1:k1
                    timemod(ik)=MyFolderInfo(ik).datenum-MyFolderInfo(1).datenum;
                end
                [B,I] = maxk(timemod,min(k1,5));
                for ik=1:min(k1,5)               
                    newitem=MyFolderInfo(I(ik)).name;newitem=newitem(1:end-5);
                    app.UsersDropDown.Items=[app.UsersDropDown.Items newitem];
                    app.UsersDropDown.Value=app.UsersDropDown.Items(2);
                end
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            if isequal(app.UsersDropDown.Value,'Add name')
                if app.StartButton.Text == 'Start'
                    app.EditField.Visible = 'on';
                    app.StartButton.Text = 'Add  ';
                else                    
                    app.StartButton.Text = 'Start';
                    if isempty(app.EditField.Value)==0
                        app.UsersDropDown.Items=[app.UsersDropDown.Items app.EditField.Value];
                        app.EditField.Value='';
                        app.UsersDropDown.Value=app.UsersDropDown.Items(end);
                    end
                    app.EditField.Visible = 'off';
                end
            else
                for iround=1:app.RoundsSpinner.Value
                    clear timeleftvector bluemax yellowmax redmax;
                    app.StartButton.Text = 'Countdown';
                    axis(app.UIAxes4,'off');
                    cam = webcam(1);
                    timer=app.TimesSpinner.Value; %practise time in seconds
                    limitmax=170;
                    frame=20;     
                    I2 = imread("gras2.jpg");
                    I3 = imread("gras.jpg");
                    I4 = imread("ocean.jpg");
                    Nperiod=10;
                    s=1:90;
                    A0=0.0002;
                    B0=-0.03;
                    C0=0.7;
                    Npoints_max=200;
                    D1=cumsum(smooth(0.003*(randi(11,Npoints_max+10,1)-6),Npoints_max/10));
                    D1=D1(5:4+Npoints_max)-D1(5);
                    D2=cumsum(smooth(0.003*(randi(11,Npoints_max+10,1)-6),Npoints_max/10));
                    D2=D2(5:4+Npoints_max)-D2(5);
                    D3=cumsum(smooth(0.003*(randi(11,Npoints_max+10,1)-6),Npoints_max/10));
                    D3=D3(5:4+Npoints_max)-D3(5);
                    start=0;
                    blue=[50 86 129];
                    yellow=[165 116 40];
                    red=[124 35 35];
                    img = snapshot(cam);[Nyc,Nxc,Nzc]=size(img);
                    img=img(:,round(Nxc/4):round(3*Nxc/4),:);[Ny,Nx,Nz]=size(img);
                    img2=double(img);
                    counter=1;
                    cla(app.UIAxes);
                    axis(app.UIAxes,'off');
                    app.UIAxes.XLim=[0 timer];app.UIAxes.YLim=[0 Ny];
                    text(app.UIAxes,timer/2,Ny/2,'3','FontSize',50,'color','y','BackgroundColor',[0,0,0.5]);pause(0.5);cla(app.UIAxes);
                    text(app.UIAxes,timer/2,Ny/2,'2','FontSize',50,'color','y','BackgroundColor',[0,0,0.5]);pause(0.5);cla(app.UIAxes);
                    text(app.UIAxes,timer/2,Ny/2,'1','FontSize',50,'color','y','BackgroundColor',[0,0,0.5]);pause(0.5);cla(app.UIAxes);
                    app.StartButton.Text = ['Test ',num2str(iround)];
                    starttime=clock;
                    timeleft=timer;
                    timeleftvector=timer;
                    
                    while timeleft>0
                        img = snapshot(cam);img=img(:,round(Nxc/4):round(3*Nxc/4),:);
                        img2=double(img);
                        hold(app.UIAxes2,'off');
                        imagesc(app.UIAxes2,img);axis(app.UIAxes2,'off');axis(app.UIAxes2,'equal');
                        
                        [kyblue1,kxblue1]=find((img2(:,:,1)-blue(1)).^2+(img2(:,:,2)-blue(2)).^2+(img2(:,:,3)-blue(3)).^2<limitmax);
                        [kyblue,kxblue]=find((img2(max(1,min(kyblue1)-frame):min(Ny,max(kyblue1)+frame),max(1,min(kxblue1)-frame):min(Nx,max(kxblue1)+frame),1)-blue(1)).^2+(img2(max(1,min(kyblue1)-frame):min(Ny,max(kyblue1)+frame),max(1,min(kxblue1)-frame):min(Nx,max(kxblue1)+frame),2)-blue(2)).^2+(img2(max(1,min(kyblue1)-frame):min(Ny,max(kyblue1)+frame),max(1,min(kxblue1)-frame):min(Nx,max(kxblue1)+frame),3)-blue(3)).^2<2*limitmax);
                        kyblue=kyblue+min(kyblue1)-frame-1;kxblue=kxblue+min(kxblue1)-frame-1;
                        
                        [kyyellow1,kxyellow1]=find((img2(:,:,1)-yellow(1)).^2+(img2(:,:,2)-yellow(2)).^2+(img2(:,:,3)-yellow(3)).^2<limitmax);
                        [kyyellow,kxyellow]=find((img2(max(1,min(kyyellow1)-frame):min(Ny,max(kyyellow1)+frame),max(1,min(kxyellow1)-frame):min(Nx,max(kxyellow1)+frame),1)-yellow(1)).^2+(img2(max(1,min(kyyellow1)-frame):min(Ny,max(kyyellow1)+frame),max(1,min(kxyellow1)-frame):min(Nx,max(kxyellow1)+frame),2)-yellow(2)).^2+(img2(max(1,min(kyyellow1)-frame):min(Ny,max(kyyellow1)+frame),max(1,min(kxyellow1)-frame):min(Nx,max(kxyellow1)+frame),3)-yellow(3)).^2<2*limitmax);
                        kyyellow=kyyellow+min(kyyellow1)-frame-1;kxyellow=kxyellow+min(kxyellow1)-frame-1;
                        
                        [kyred1,kxred1]=find((img2(:,:,1)-red(1)).^2+(img2(:,:,2)-red(2)).^2+(img2(:,:,3)-red(3)).^2<limitmax);
                        [kyred,kxred]=find((img2(max(1,min(kyred1)-frame):min(Ny,max(kyred1)+frame),max(1,min(kxred1)-frame):min(Nx,max(kxred1)+frame),1)-red(1)).^2+(img2(max(1,min(kyred1)-frame):min(Ny,max(kyred1)+frame),max(1,min(kxred1)-frame):min(Nx,max(kxred1)+frame),2)-red(2)).^2+(img2(max(1,min(kyred1)-frame):min(Ny,max(kyred1)+frame),max(1,min(kxred1)-frame):min(Nx,max(kxred1)+frame),3)-red(3)).^2<2*limitmax);
                        kyred=kyred+min(kyred1)-frame-1;kxred=kxred+min(kxred1)-frame-1;
                        
                        if isempty(kyblue)
                            kxblue=0;kyblue=0;
                        end
                        if isempty(kyyellow)
                            kxyellow=0;kyyellow=0;
                        end
                        if isempty(kyred)
                            kxred=0;kyred=0;
                        end
                        bluemax(counter)=min(kyblue);
                        yellowmax(counter)=min(kyyellow);
                        redmax(counter)=min(kyred);
                        
                        
                        hold(app.UIAxes2,'on');
                        plot(app.UIAxes2,kxblue,kyblue,'b.');
                        plot(app.UIAxes2,kxyellow,kyyellow,'y.');
                        plot(app.UIAxes2,kxred,kyred,'r.');
                                         
                        timeleftvector(counter)=timeleft;
                        
                        if app.ShowDropDown.Value=='0'
                            hold(app.UIAxes,'off');
                            plot(app.UIAxes,timer-timeleftvector,bluemax,'b.-','Linewidth',2);
                            hold(app.UIAxes,'on');
                            plot(app.UIAxes,timer-timeleftvector,yellowmax,'y.-','Linewidth',2);
                            plot(app.UIAxes,timer-timeleftvector,redmax,'r.-','Linewidth',2);
                            app.UIAxes.XLim=[0 app.TimesSpinner.Value];
                            app.UIAxes.YLim=[0,Nyc];
                        end
        
                        counter=counter+1;
                        currenttime=clock;
                        time=dot(currenttime-starttime,[0;0;3600*25;3600;60;1]);
                        timeleft=timer-time;
                        kblue=find(bluemax<Ny/2);kyellow=find(yellowmax<Ny/2);kred=find(redmax<Ny/2);
                        
                        if app.ShowDropDown.Value=='1'
                            hold(app.UIAxes,'off');
                            imagesc(app.UIAxes,I2);axis(app.UIAxes,'tight'); 
                            size(I2)
                            hold(app.UIAxes,'on');
                            plot(app.UIAxes,1280/4,700-length(kblue)*90/timer,'bo','MarkerFaceColor', 'b');
                            plot(app.UIAxes,1280/4*2,700-length(kyellow)*90/timer,'ko','MarkerFaceColor','y');
                            plot(app.UIAxes,1280/4*3,700-length(kred)*90/timer,'ro','MarkerFaceColor','r');
                            %app.UIAxes.XLim=[0 4];app.UIAxes.YLim=[0 Ny];
                            %plot(app.UIAxes,0,10,'k');
                            %XLim(app.UIAxes,[0,4]);
                            %app.UIAxes.YLim=[0 100];
                        
                        end
                        if app.ShowDropDown.Value=='2'
                            hold(app.UIAxes,'off');
                            imagesc(app.UIAxes,[-4,4],[-4,4],I3);axis(app.UIAxes,'tight');
                            hold(app.UIAxes,'on');
                            plot(app.UIAxes,2.8*sind(4*length(kblue)),2.8*cosd(4*length(kblue)),'bo','MarkerFaceColor', 'b');
                            app.UIAxes.XLim=[-4 4];app.UIAxes.YLim=[-4 4];
                            plot(app.UIAxes,3.1*sind(4*length(kyellow)),3.1*cosd(4*length(kyellow)),'ko','MarkerFaceColor','y');
                            plot(app.UIAxes,3.4*sind(4*length(kred)),3.4*cosd(4*length(kred)),'ro','MarkerFaceColor','r');
                            plot(app.UIAxes,0,10,'k');
                            plot(app.UIAxes,2.5*sind((0:360)),2.5*cosd((0:360)),'-','Color','k');
                            plot(app.UIAxes,3.7*sind((0:360)),3.7*cosd((0:360)),'-','Color','k');
                            %XLim(app.UIAxes,[0,4]);
                            %app.UIAxes.YLim=[0 100];
                        
                        end
                        if app.ShowDropDown.Value=='4'
                            B=B0+0.001*sin(timeleft/app.TimesSpinner.Value*Nperiod*2*pi);
                            A=A0+0.00005*sin(timeleft/app.TimesSpinner.Value*Nperiod*2*pi);
                            C=C0+0.3*sin(timeleft/app.TimesSpinner.Value*Nperiod*2*pi);
                            phi0=A*s.^2+B*s+C;
                            x1=cumsum(cos(phi0));x1=[-x1(end:-1:1),x1];
                            y1=-cumsum(sin(phi0));y1=[y1(end:-1:1),y1];
                            x2=cumsum(cos(phi0));x2=[-x2(end:-1:1),x2];
                            y2=-cumsum(sin(phi0));y2=[y2(end:-1:1),y2];
                            x3=cumsum(cos(phi0));x3=[-x3(end:-1:1),x3];
                            y3=-cumsum(sin(phi0));y3=[y3(end:-1:1),y3];
                            xR1=x1+Nxc/4;
                            yR1=y1+650-length(kblue)*90/timer;
                            xR2=x2+2*Nxc/4;
                            yR2=y2+650-length(kyellow)*90/timer;
                            xR3=x3+3*Nxc/4;
                            yR3=y3+650-length(kred)*90/timer;
                            hold(app.UIAxes,'off');
                            imagesc(app.UIAxes,[0,Nxc],[0,Nyc],I4);axis(app.UIAxes,'tight');
                            hold(app.UIAxes,'on');
                     
                            plot(app.UIAxes,xR1,yR1,'-','Linewidth',3,'color','b');                       
                            plot(app.UIAxes,xR2,yR2,'-','Linewidth',3,'color','y');
                            plot(app.UIAxes,xR3,yR3,'-','Linewidth',3,'color','r');
                        
                        end
                    end
                    
                    kb=round(100*length(find(bluemax<max(0,min(bluemax))+0.5*(max(bluemax)-max(0,min(bluemax)))))/length(bluemax));
                    ky=round(100*length(find(yellowmax<max(0,min(yellowmax))+0.5*(max(yellowmax)-max(0,min(yellowmax)))))/length(yellowmax));
                    kr=round(100*length(find(redmax<max(0,min(redmax))+0.5*(max(redmax)-max(0,min(redmax)))))/length(redmax));
                    cla(app.UIAxes)
                    %hold(app.UIAxes,'off');
                    axis(app.UIAxes,'off');
                    text(app.UIAxes,0.1,0.1,'blue: ','FontSize',40,'color',[0.5,0.5,1],'BackgroundColor',[0,0,0.5]);
                    hold(app.UIAxes,'on');
                    text(app.UIAxes,0.5,0.1,[num2str(kb),' %'],'FontSize',40,'color',[0.5,0.5,1],'BackgroundColor',[0,0,0.5]);
                    text(app.UIAxes,0.1,0.3,'yellow: ','FontSize',40,'color','y','BackgroundColor',[0,0,0.5]);
                    text(app.UIAxes,0.5,0.3,[num2str(ky),' %'],'FontSize',40,'color','y','BackgroundColor',[0,0,0.5]);
                    text(app.UIAxes,0.1,0.5,'red: ','FontSize',40,'color','r','BackgroundColor',[0,0,0.5]);
                    text(app.UIAxes,0.5,0.5,[num2str(kr),' %'],'FontSize',40,'color','r','BackgroundColor',[0,0,0.5]);
                    text(app.UIAxes,0.1,0.7,'Total: ','FontSize',40,'color','y','BackgroundColor',[0,0,0.5]);
                    text(app.UIAxes,0.5,0.7,[num2str(kr+kb+ky),' Points'],'FontSize',40,'color','y');
                    app.UIAxes.YLim=[0 1];app.UIAxes.XLim=[0 1.1];
                    savedata.user=app.UsersDropDown.Value;
                    savedata.rounds=app.RoundsSpinner.Value;
                    savedata.timer=app.TimesSpinner.Value;
                    savedata.points=(kr+kb+ky);
                    savedata.blue=kb;savedata.yellow=ky;savedata.red=kr;
                    savedata.date=date;
                    savedata.time=clock;
                    savedata.bluemax=bluemax;savedata.yellowmax=yellowmax;savedata.redmax=redmax;
                    T = struct2table(savedata);
                    writetable(T,[app.UsersDropDown.Value,'.xlsx'], "FileType", 'Spreadsheet',"WriteMode",'append');
                    app.StartButton.Text = 'Start';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [0 700];
            app.UIAxes2 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes4, 'Z')
            app.TimesSpinnerLabel = uilabel(app.UIFigure);
            app.TimesSpinnerLabel.Text = 'Time [s]:';
            app.TimesSpinner = uispinner(app.UIFigure);
            app.TimesSpinner.Value = 3;
            app.RoundsSpinnerLabel = uilabel(app.UIFigure);
            app.RoundsSpinnerLabel.Text = 'Rounds: ';
            app.RoundsSpinner = uispinner(app.UIFigure);
            app.RoundsSpinner.Limits = [0 Inf];
            app.RoundsSpinner.Value = 1;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ShowDropDownLabel = uilabel(app.UIFigure);
            app.ShowDropDownLabel.Text = 'Show';
            app.ShowDropDown = uidropdown(app.UIFigure);
            app.ShowDropDown.Items = {'Height', 'Car race', 'Camels  ', 'Plots   ', 'Birds'};
            app.ShowDropDown.ItemsData = {'0', '1', '2', '3', '4'};
            app.ShowDropDown.Value = '0';
            app.UsersDropDownLabel = uilabel(app.UIFigure);
            app.UsersDropDownLabel.Text = 'Users';
            app.UsersDropDown = uidropdown(app.UIFigure);
            app.UsersDropDown.Items = {'Add name'};
            app.UsersDropDown.Value = 'Add name';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LUNGENTRAINER_APP

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end